classdef Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ArchivoTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_4      matlab.ui.control.Button
        CargarArchivoButton             matlab.ui.control.Button
        EscojaunarchivoLabel            matlab.ui.control.Label
        CargarrespuestaImpulsionalButton  matlab.ui.control.Button
        RimpulsionalLabel               matlab.ui.control.Label
        Canal1Tab                       matlab.ui.container.Tab
        DistorcinCheckBox               matlab.ui.control.CheckBox
        VibratoCheckBox                 matlab.ui.control.CheckBox
        EchoDelayCheckBox               matlab.ui.control.CheckBox
        ReverberacinCheckBox            matlab.ui.control.CheckBox
        PhaserCheckBox                  matlab.ui.control.CheckBox
        FrecuenciadecentroEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecentroEditField     matlab.ui.control.NumericEditField
        FactordecalidadEditField_4Label  matlab.ui.control.Label
        FactordecalidadEditField_4      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        ReproduciroriginalButton        matlab.ui.control.Button
        ReproducirnuevoButton           matlab.ui.control.Button
        FiltroDropDownLabel             matlab.ui.control.Label
        FiltroDropDown                  matlab.ui.control.DropDown
        AplicarefectosButton            matlab.ui.control.Button
        msdeatrasoEditFieldLabel        matlab.ui.control.Label
        msdeatrasoEditField             matlab.ui.control.NumericEditField
        FreqHzSlider_3Label             matlab.ui.control.Label
        FreqHzSlider_3                  matlab.ui.control.Slider
        GananciaKnobLabel               matlab.ui.control.Label
        GananciaKnob                    matlab.ui.control.Knob
        FreqHzSliderLabel               matlab.ui.control.Label
        FreqHzSlider                    matlab.ui.control.Slider
        FreqHzEditFieldLabel            matlab.ui.control.Label
        FreqHzEditField                 matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        DelaySlider_2Label              matlab.ui.control.Label
        DelaySlider_2                   matlab.ui.control.Slider
        PararaudioButton                matlab.ui.control.Button
        Canal2Tab                       matlab.ui.container.Tab
        DistorcinCheckBox_2             matlab.ui.control.CheckBox
        VibratoCheckBox_2               matlab.ui.control.CheckBox
        EchoDelayCheckBox_2             matlab.ui.control.CheckBox
        ReverberacinCheckBox_2          matlab.ui.control.CheckBox
        PhaserCheckBox_2                matlab.ui.control.CheckBox
        FrecuenciadecentroEditField_2Label  matlab.ui.control.Label
        FrecuenciadecentroEditField_2   matlab.ui.control.NumericEditField
        FactordecalidadEditField_5Label  matlab.ui.control.Label
        FactordecalidadEditField_5      matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortealtaEditField_2  matlab.ui.control.NumericEditField
        ReproduciroriginalButton_2      matlab.ui.control.Button
        ReproducirnuevoButton_2         matlab.ui.control.Button
        FiltroDropDown_2Label           matlab.ui.control.Label
        FiltroDropDown_2                matlab.ui.control.DropDown
        AplicarefectosButton_2          matlab.ui.control.Button
        msdeatrasoEditField_2Label      matlab.ui.control.Label
        msdeatrasoEditField_2           matlab.ui.control.NumericEditField
        GananciaKnob_2Label             matlab.ui.control.Label
        GananciaKnob_2                  matlab.ui.control.Knob
        FreqHzSlider_4Label             matlab.ui.control.Label
        FreqHzSlider_4                  matlab.ui.control.Slider
        FreqHzSlider_2Label             matlab.ui.control.Label
        FreqHzSlider_2                  matlab.ui.control.Slider
        DelaySliderLabel                matlab.ui.control.Label
        DelaySlider                     matlab.ui.control.Slider
        FreqHzEditField_2Label          matlab.ui.control.Label
        FreqHzEditField_2               matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditField_2Label  matlab.ui.control.Label
        FrecuenciadecortebajaEditField_2  matlab.ui.control.NumericEditField
        GrficasfiltroTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ReproduciroriginalButton_3      matlab.ui.control.Button
        ReproducirnuevoButton_3         matlab.ui.control.Button
        GrficasdeaudioTab               matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        ReproduciroriginalButton_5      matlab.ui.control.Button
        ReproducirnuevoButton_4         matlab.ui.control.Button
        GuardarTab                      matlab.ui.container.Tab
        ReproduciroriginalButton_6      matlab.ui.control.Button
        ReproducirnuevoButton_5         matlab.ui.control.Button
        GuardarnuevocomowavButton       matlab.ui.control.Button
        GuardarvariablescomomatButton   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [y2, t2]= delay_funcion(app,x, delay_ms, alfa, Fs)
            %Tiempo de muestreo
            ts = 1/Fs;
            
            %Calculo del numero de muestras que ocupa el delay
            delay_muestra = round((delay_ms/1000)*Fs);
            %Es importante que el tipo de dato de esta operación sea entero
            
            %tamaño de salida de la señal (numero de filas del vector columna)
            N = size(x,1);
        
            %reservar el espacio de memoria (vector columna)
            y2 = zeros(N,1);
            
            %Ecuación de Diferencias
            %Aplicamos la ecuación de diferencias
            for i = 1:N
                if i>delay_muestra
                    y2(i) = x(i) + alfa*x(i-delay_muestra);
                end
                if i<=delay_muestra
                    y2(i) =  x(i);
                end
        
            end
        
            %pasamos de muestras a tiempo
            t2 = ts*(0:(N-1));
            t2 = t2.'; %Cambiamos de vector fila a vector columna
        
            %Salidas
            %y2 es la señal procesada
            %t2 es el vector de tiempo para graficar la señal (s)
            
            %Entradas
            %x es la señal de entrada
            %delay_ms es el delay en milisegundos
            %alfa es la ganacia del efecto
            %Fs es la frecuencia de muestreo
            
        end
        
        function y = reverb_funcion(app,x, h)
            %Se hace la convolución
            y = conv(x,h);
            %Normalizamos dividiendo entre el máximo absoluto
            m = max(y);
            g = abs(min(y)); %sacamos el valor absoluto de los extremos
            star = max(m,g); %Averiguamos cuál de los dos es mayor
            y = y/star; %Devolvemos el valor normalizado
        end


        function y = vibrato_funcion(app, x, frec, ms, fs)
            %Pasamos a segundos 
            seg = ms / 1000; 
            %Delay 
            delay=round(seg*fs); % Delay base, per o en muestras
            segpulso=round(seg*fs); % modulacion del pulso, en muestras
            l=length(x);
            ancho=2+delay+segpulso*2; % Ancho del delay
            
            % Crear vectores contenedores
            ceros=zeros(ancho,1);
            y=zeros(size(x));
            
            % Creando Efecto
            m = frec/fs;
            for n=1:(l-1)
                alt = sin(m*2*pi*n);
                mod = 1+delay+segpulso*alt;
                i=floor(mod);
                ceros=[x(n);ceros(1:ancho-1)];
                y(n,1)=ceros(i+1).*(mod-i)+ceros(i).*(1-(mod-i));
            end
        end


        function y = distorcion_funcion(app, x, fc, fs)
            largo = 1:length(x);
            dist = sin(2*pi.*(fc/fs).*largo);
            temp1 =dist';
            y = x.*temp1;
        end
        
        function y = phaser_function(app ,x, fs)
            %x es el array/vector del audio
            %fs es la frecuencia de muestreo
            delay = 25; %15 son los valores experimentales que suenan bien
            range = 50; %12 aunque se pueden modificar para que el phaser 
            sweep_freq = 0.3125/2; %0.3125 tenga diferentes propiedades
            N = length(x(:,1))-delay-range;
            %reservar el espacio de memoria
            y = zeros(N,1);
            %aplicar ecuación de diferencias
            for i = 1:N
                temp = i - delay - round(range*sin(2*pi*i*sweep_freq/fs));
                %Acá solo nos aseguramos de que la señal siga igual siempre y cuando 
                %i sea positivo porque matlab no admite coeficientes negativos
                %el problema se elimina sumando delay y round() pero esto hace que el
                %sistema deje de ser causal, lo que ocasiona que no se pueda utilizar
                %para procesar audio en tiempo real. Únicamente podría procesar audio
                %cargado a la memoria, en este caso no importa pero lo dejé así 
                %(signo negativo) para
                %utilizar las mismas convenciones que utilizamos en la teoría.
            
                if (temp>0)
                    y(i) = x(i) + x(temp);
                else
                    y(i) = x(i);
                end
            
            end
            %delay es el delay por defecto que tiene la señal
            %range es la amplitud del efecto
            %sweep_freq es la frecuencia con la que cambiamos la frecuencia de
            %corte del notch y el pasabandas.
        end


        function y = pasabandas(~, x, fa, fb, fs)
            N = 200;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            f = fa/fs;
            baja = (2*f*sinc(2*f*n))' .* w;

            f = fb/fs;
            alta = (sinc(n) - 2*f*sinc(n.*2*f))' .* w;

            ybaja = filter(baja, (1), x);
            ybanda = filter(alta, (1), ybaja);
            y = ybanda;
        end
        
       
        function y = freccentro(app, x, fc,q,fs)
            N = 200;
            n = -floor(N/2):floor(N/2);
            w = hamming(N);
            fa= fc/q;
            fb= 1;
            f = fa/fs;
            baja = (2*f*sinc(2*f*n))' .* w;

            f = fb/fs;
            alta = (sinc(n) - 2*f*sinc(n.*2*f))' .* w;

            ybaja = filter(baja, (1), x);
            ybanda = filter(alta, (1), ybaja);
            y = ybanda;
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            global file;
            global fs;
            global og;
            global canal1;
            global canal2;
            global canales;
            [file] = uigetfile('*.wav;*.mp3');
            %Si no hay archivo el texto le dice al usuario que escoja uno
            if (file ==0)  
                app.EscojaunarchivoLabel.Text = ("Escoja un archivo");                
            %Si sí hay archivo, se ven los datos del archivo y el texto
            %dice el nombre del archivo que se escogio 
            else
                [og,fs]= audioread(file);
                app.EscojaunarchivoLabel.Text = file;
                %Vemos cuantos canales tienen y se guarda en una variable
                canales= size(og,2);
                %app.EscojaunarchivoLabel.Text = canales;
                if (canales==2)
                    canal1= og(:,1);
                    canal2= og(:,2);
                                   
                elseif (canales==1)
                    canal1=og;
                
                    
                end

           
                %Para graficar el audio original              
                N = size(og,1);
                ogX1=fft(og);
                f = fs*(0:(N/2))/N;
                ogP2 = abs(ogX1)/N;
                ogP1 = ogP2(1:N/2+1);
                ogP1(2:end-1) = 2*ogP1(2:end-1);
                        
                plot(app.UIAxes3, f, ogP1);

                                       
            end
        end

        % Button pushed function: ReproduciroriginalButton, 
        % ReproduciroriginalButton_2, ReproduciroriginalButton_3, 
        % ReproduciroriginalButton_4, ReproduciroriginalButton_5, 
        % ReproduciroriginalButton_6
        function ReproduciroriginalButtonPushed(app, event)
            global fs;
            global og;
            soundsc(og, fs);
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            global filtro1;
            global canal1;
            global fs;
            global banda1;
            global frecortea1;
            global frecorteb1;
            global frecentro;
            global calidad;
                filtro1 = app.FiltroDropDown.Value;  
                if (filtro1=="Freq. centro y factor de calidad")
                    banda1= app.freccentro(canal1, frecentro, calidad,fs);      
                    plot(app.UIAxes, fs, banda1);
                end
                if (filtro1== "Frecuencias de corte")
                    banda1 = app.pasabandas(canal1, frecortea1, frecorteb1, fs);
                    plot(app.UIAxes, fs, banda1);
                end
        end

        % Value changed function: FiltroDropDown_2
        function FiltroDropDown_2ValueChanged(app, event)
            global filtro2;
            global canal2;
            global fs;
            global banda2;
            global frecortea2;
            global frecorteb2;
            global frecentro2;
            global calidad2;
                filtro2 = app.FiltroDropDown_2.Value;  
                if (filtro2=="Freq. centro y factor de calidad")
                    banda2= app.freccentro(canal2, frecentro2, calidad2,fs);
                    plot(app.UIAxes2, fs, banda2);
                end
                if (filtro2== "Frecuencias de corte")
                    banda2 = app.pasabandas(canal2, frecortea2, frecorteb2, fs);
                    plot(app.UIAxes2, fs, banda2);
                    
                end
        end

        % Value changed function: FrecuenciadecentroEditField
        function FrecuenciadecentroEditFieldValueChanged(app, event)
            global frecentro;
            frecentro = app.FrecuenciadecentroEditField.Value;
            
        end

        % Value changed function: FactordecalidadEditField_4
        function FactordecalidadEditField_4ValueChanged(app, event)
            global calidad;
            calidad = app.FactordecalidadEditField_4.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField
        function FrecuenciadecortealtaEditFieldValueChanged(app, event)
            global frecortea1;
            frecortea1 = app.FrecuenciadecortealtaEditField.Value;
           
        end

        % Value changed function: FrecuenciadecortebajaEditField
        function FrecuenciadecortebajaEditFieldValueChanged(app, event)
            global frecorteb1
            frecorteb1 = app.FrecuenciadecortebajaEditField.Value;
            
        end

        % Value changed function: FrecuenciadecentroEditField_2
        function FrecuenciadecentroEditField_2ValueChanged(app, event)
            global frecentro2;
            frecentro2 = app.FrecuenciadecentroEditField_2.Value;
        end

        % Value changed function: FactordecalidadEditField_5
        function FactordecalidadEditField_5ValueChanged(app, event)
            global calidad2;
            calidad2 = app.FactordecalidadEditField_5.Value;
            
        end

        % Value changed function: FrecuenciadecortealtaEditField_2
        function FrecuenciadecortealtaEditField_2ValueChanged(app, event)
            global frecortea2;
            frecortea2 = app.FrecuenciadecortealtaEditField_2.Value;
            
        end

        % Value changed function: FrecuenciadecortebajaEditField_2
        function FrecuenciadecortebajaEditField_2ValueChanged(app, event)
            global frecorteb2
            frecorteb2 = app.FrecuenciadecortebajaEditField_2.Value;
            
        end

        % Value changed function: DistorcinCheckBox
        function DistorcinCheckBoxValueChanged(app, event)
            %Filtro distorción 1
            %Cada uno de estos filtros hace lo suyo, y genera un yd1, yv1 y asi
            %respectivamente. Realizar la suma ys que se generan 
            global distortion1_on;
            distortion1_on = app.DistorcinCheckBox.Value;
            
            
        end

        % Value changed function: VibratoCheckBox
        function VibratoCheckBoxValueChanged(app, event)
            %Filtro vibrato 1
            global vibrato_on;
            vibrato_on = app.VibratoCheckBox.Value;
        end

        % Value changed function: ReverberacinCheckBox
        function ReverberacinCheckBoxValueChanged(app, event)
            %Filtro reverberacion 1

            global reverb1_on;
            reverb1_on = app.ReverberacinCheckBox.Value;
            
        end

        % Value changed function: PhaserCheckBox
        function PhaserCheckBoxValueChanged(app, event)
            %Filtro phaser 1
            global phaser1_on;
            
            phaser1_on = app.PhaserCheckBox.Value;
        end

        % Value changed function: EchoDelayCheckBox
        function EchoDelayCheckBoxValueChanged(app, event)
            %Filtro echo 1
            global delay1_on;
            delay1_on = app.EchoDelayCheckBox.Value;


        end

        % Value changed function: DistorcinCheckBox_2
        function DistorcinCheckBox_2ValueChanged(app, event)
            %Filtro distorcion 2
            global distortion2_on;
            distortion2_on = app.DistorcinCheckBox_2.Value;
        end

        % Value changed function: VibratoCheckBox_2
        function VibratoCheckBox_2ValueChanged(app, event)
            %Filtro vibrato 2
            global fs;
            global banda2;
            global yv2;        
            global usvib2;
            global ganancia2;
            if (banda2~=0)
                value = app.VibratoCheckBox_2.Value;
                if (value==1)
                    ms=500;
                    yv2 = app.vibrato_funcion(banda2, usvib2, ms, fs, ganancia2);
    
                else
                   yv2=zeros(size(banda2));
                end
            end

        end

        % Value changed function: ReverberacinCheckBox_2
        function ReverberacinCheckBox_2ValueChanged(app, event)
            %Filtro reverberacion 2
            global reverb2_on;
            reverb2_on = app.ReverberacinCheckBox_2.Value;
        end

        % Value changed function: PhaserCheckBox_2
        function PhaserCheckBox_2ValueChanged(app, event)
            %Filtro phaser 2
            global phaser2_on;
            phaser2_on = app.PhaserCheckBox_2.Value;
            
        end

        % Value changed function: EchoDelayCheckBox_2
        function EchoDelayCheckBox_2ValueChanged(app, event)
            %Filtro echo 2
            global delay2_on;
            delay2_on = app.EchoDelayCheckBox_2.Value;
        end

        % Button pushed function: AplicarefectosButton
        function AplicarefectosButtonPushed(app, event)
            %Probar efectos 1
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global og;
            global canal1;
            global banda1;
            global phaser_out;
            global reverb1_on;
            global reverb_out;
            %vibrato
            global vibrato_on;
            global usvib1;
            global ypc2;
            global y1;
            global y2;
            global ganancia1;
            global phaser1_on;
            global impulse_response;
            global delay1_on;
            global delay1_ms;            
            global delay_out;
            global distortion1_on;
            global distortion_out;
            global usdis1;
            global new;
            %verificamos si es necesario aplicar phaser
            
            new = canal1;
            canales= size(og,2);
            %app.EscojaunarchivoLabel.Text = canales;
            if (canales==2)
                
                
                if (phaser1_on==1)
                    phaser_out = app.phaser_function(new, fs);
                    %phaser_out(:,2) = app.phaser_function(og(:,2), fs);
                    new = phaser_out;
                end
                
                if (reverb1_on==1)
                    reverb_out = reverb_funcion(app, new, impulse_response);
                    new = reverb_out;
                end

                if (delay1_on==1)
                    alfa = 0.8; %pruebas
                    delay_out = delay_funcion(app, new, delay1_ms, alfa, fs);
                    new = delay_out;
                end
                
                if (distortion1_on==1)                    
                    distortion_out = distorcion_funcion(app, new, usdis1, fs);
                    new = distortion_out;

                end
                if (vibrato_on ==1)
                    ms=500;
                    vibrato_out = vibrato_funcion(app, new, 10, ms, fs);
                    new = vibrato_out;
                end
                               
            elseif (canales==1)
                
                if (phaser1_on==1)
                    phaser_out = app.phaser_function(new, fs);
    
                end
                if (delay1_on==1)
                    alfa = 0.8; %pruebas
                    delay_out = delay_funcion(app, new, delay1_ms, alfa, fs);
                else
                    delay_out = canal1;
                    
                end
                
                if (distortion1_on==1)                    
                    distortion_out = distorcion_funcion(app, new, usdis1, fs);
                end
                
            end
            %new = distortion_out;
%             N = size(og,1);
%             newX1=fft(new);
%             f = fs*(0:(N/2))/N;
%             newP2 = abs(newX1)/N;
%             newP1 = newP2(1:N/2+1);
%             newP1(2:end-1) = 2*newP1(2:end-1);
%                     
%             plot(app.UIAxes4, f, newP1);
                
            
        end

        % Button pushed function: AplicarefectosButton_2
        function AplicarefectosButton_2Pushed(app, event)
            %Probar efectos 2
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            global fs;
            global og;
            global canales;
            
            %global yd2;
            %global yr2;
            %global yv2;
            %global ye2;
            global reverb2_on;
            global reverb_out;
            global impulse_response;
            global y2;
            global y1;
            global phaser_out;
            global phaser2_on;
            global delay2_ms;
            global canal2;
            global delay_out;
            global delay2_on;
            global distortion_out;
            global distortion2_on;
            global udis2;
            global new;
            %Este boton solo funciona si tiene 2 canales, si tiene 1 canal se modifica en el canal 1
            if (canales==2)
                            
                            
                if (phaser2_on==1)
                    phaser_out(:,2) = app.phaser_function(og(:,2), fs);
    
                end
                
                if (delay2_on==1)
                    alfa = 0.8; %pruebas
                    delay_out(:,2) = delay_funcion(app, canal2, delay2_ms, alfa, fs);
                end        
                
                if (reverb2_on==1)
                    reverb_out(:,2) = reverb_funcion(app, canal2, impulse_response);
                end
                
                if (distortion2_on==1)                    
                    distortion_out(:,2) = distorcion_funcion(app, canal2, udis2, fs);
                end
                            
            end


        end

        % Value changed function: FreqHzEditField
        function FreqHzEditFieldValueChanged(app, event)
            global usdis1;
            usdis1 = app.FreqHzEditField.Value;
            
        end

        % Value changed function: FreqHzSlider
        function FreqHzSliderValueChanged(app, event)
            global usrev1;
            usrev1 = app.FreqHzSlider.Value;
            
        end

        % Value changed function: FreqHzEditField_2
        function FreqHzEditField_2ValueChanged(app, event)
            global usdis2;
            usdis2 = app.FreqHzEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_2
        function FreqHzSlider_2ValueChanged(app, event)
            global usrev2;
            usrev2 = app.FreqHzSlider_2.Value;
            
        end

        % Value changed function: msdeatrasoEditField
        function msdeatrasoEditFieldValueChanged(app, event)
            global delay1_ms;
            delay1_ms = app.msdeatrasoEditField.Value;
            
        end

        % Value changed function: msdeatrasoEditField_2
        function msdeatrasoEditField_2ValueChanged(app, event)
            global delay2_ms;
            delay2_ms = app.msdeatrasoEditField_2.Value;
            
        end

        % Value changed function: FreqHzSlider_3
        function FreqHzSlider_3ValueChanged(app, event)
            global usvib1;
            usvib1 = app.FreqHzSlider_3.Value;
            
        end

        % Value changed function: FreqHzSlider_4
        function FreqHzSlider_4ValueChanged(app, event)
            global usvib2;
            usvib2 = app.FreqHzSlider_4.Value;
        end

        % Value changed function: DelaySlider_2
        function DelaySlider_2ValueChanged(app, event)
            global phaser_delay_1;
            phaser_delay_1 = app.DelaySlider_2.Value;
            
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            global phaser_delay_2;
            phaser_delay_2 = app.DelaySlider.Value;
            
        end

        % Value changed function: GananciaKnob
        function GananciaKnobValueChanged(app, event)
            global ganancia1;
            ganancia1 = app.GananciaKnob.Value;
            
        end

        % Value changed function: GananciaKnob_2
        function GananciaKnob_2ValueChanged(app, event)
            global ganancia2;
            ganancia2 = app.GananciaKnob_2.Value;
            
        end

        % Button pushed function: ReproducirnuevoButton, 
        % ReproducirnuevoButton_2, ReproducirnuevoButton_3, 
        % ReproducirnuevoButton_4, ReproducirnuevoButton_5
        function ReproducirnuevoButtonPushed(app, event)
            global fs;
            global new;
            global phaser_out;
            soundsc(new,fs);
        end

        % Button pushed function: CargarrespuestaImpulsionalButton
        function CargarrespuestaImpulsionalButtonPushed(app, event)
            %Callback cuando se carga la respuesta impulsional
            global impulse_response;
            global impulse_response_file;
            global impulse_fs;
            [impulse_response_file] = uigetfile('*.wav;*.mp3');
            if (impulse_response_file ~=0)
                [impulse_response,impulse_fs]= audioread(impulse_response_file);
                app.RimpulsionalLabel.Text = impulse_response_file;
            end            
            %Si no hay archivo el texto le dice al usuario que escoja uno
%             if (impulse_response_file ==0)  
%                 app.RimpulsionalLabel.Text = ("Escoja un archivo");                
%             %Si sí hay archivo, se ven los datos del archivo y el texto
%             %dice el nombre del archivo que se escogio 
%             else
%                 [impulse_response,impulse_fs]= audioread(impulse_response_file);
%                 app.RimpulsionalLabel.Text = impulse_response_file;


        end

        % Button pushed function: PararaudioButton
        function PararaudioButtonPushed(app, event)
            clear sound;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.ReproduciroriginalButton_4 = uibutton(app.ArchivoTab, 'push');
            app.ReproduciroriginalButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_4.Text = 'Reproducir original';
            app.CargarArchivoButton = uibutton(app.ArchivoTab, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.EscojaunarchivoLabel = uilabel(app.ArchivoTab);
            app.EscojaunarchivoLabel.Text = {'Escoja un archivo'; ''};
            app.CargarrespuestaImpulsionalButton = uibutton(app.ArchivoTab, 'push');
            app.CargarrespuestaImpulsionalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarrespuestaImpulsionalButtonPushed, true);
            app.CargarrespuestaImpulsionalButton.Text = {'Cargar respuesta'; 'Impulsional'};
            app.RimpulsionalLabel = uilabel(app.ArchivoTab);
            app.RimpulsionalLabel.Text = {'R impulsional'; ''};
            app.Canal1Tab = uitab(app.TabGroup);
            app.Canal1Tab.Title = 'Canal 1';
            app.DistorcinCheckBox = uicheckbox(app.Canal1Tab);
            app.DistorcinCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBoxValueChanged, true);
            app.DistorcinCheckBox.Text = 'Distorción';
            app.VibratoCheckBox = uicheckbox(app.Canal1Tab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBoxValueChanged, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.EchoDelayCheckBox = uicheckbox(app.Canal1Tab);
            app.EchoDelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBoxValueChanged, true);
            app.EchoDelayCheckBox.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox = uicheckbox(app.Canal1Tab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBoxValueChanged, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.PhaserCheckBox = uicheckbox(app.Canal1Tab);
            app.PhaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBoxValueChanged, true);
            app.PhaserCheckBox.Text = 'Phaser ';
            app.FrecuenciadecentroEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecentroEditFieldLabel.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecentroEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditFieldValueChanged, true);
            app.FactordecalidadEditField_4Label = uilabel(app.Canal1Tab);
            app.FactordecalidadEditField_4Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_4 = uieditfield(app.Canal1Tab, 'numeric');
            app.FactordecalidadEditField_4.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_4ValueChanged, true);
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortealtaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditFieldValueChanged, true);
            app.ReproduciroriginalButton = uibutton(app.Canal1Tab, 'push');
            app.ReproduciroriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton.Text = 'Reproducir original';
            app.ReproducirnuevoButton = uibutton(app.Canal1Tab, 'push');
            app.ReproducirnuevoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton.Text = 'Reproducir nuevo';
            app.FiltroDropDownLabel = uilabel(app.Canal1Tab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Canal1Tab);
            app.FiltroDropDown.Items = {'Freq. centro y factor de calidad', 'Frecuencias de corte'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'Frecuencias de corte';
            app.AplicarefectosButton = uibutton(app.Canal1Tab, 'push');
            app.AplicarefectosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButtonPushed, true);
            app.AplicarefectosButton.Text = 'Aplicar efectos';
            app.msdeatrasoEditFieldLabel = uilabel(app.Canal1Tab);
            app.msdeatrasoEditFieldLabel.Text = 'ms de atraso';
            app.msdeatrasoEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.msdeatrasoEditField.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditFieldValueChanged, true);
            app.FreqHzSlider_3Label = uilabel(app.Canal1Tab);
            app.FreqHzSlider_3Label.Text = 'Freq.(Hz)';
            app.FreqHzSlider_3 = uislider(app.Canal1Tab);
            app.FreqHzSlider_3.Limits = [0 10];
            app.FreqHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_3ValueChanged, true);
            app.GananciaKnobLabel = uilabel(app.Canal1Tab);
            app.GananciaKnobLabel.Text = 'Ganancia';
            app.GananciaKnob = uiknob(app.Canal1Tab, 'continuous');
            app.GananciaKnob.Limits = [0 1];
            app.GananciaKnob.ValueChangedFcn = createCallbackFcn(app, @GananciaKnobValueChanged, true);
            app.FreqHzSliderLabel = uilabel(app.Canal1Tab);
            app.FreqHzSliderLabel.Text = 'Freq. (Hz)';
            app.FreqHzSlider = uislider(app.Canal1Tab);
            app.FreqHzSlider.Limits = [0 5];
            app.FreqHzSlider.ValueChangedFcn = createCallbackFcn(app, @FreqHzSliderValueChanged, true);
            app.FreqHzEditFieldLabel = uilabel(app.Canal1Tab);
            app.FreqHzEditFieldLabel.Text = 'Freq. (Hz) ';
            app.FreqHzEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditFieldValueChanged, true);
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecortebajaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditFieldValueChanged, true);
            app.DelaySlider_2Label = uilabel(app.Canal1Tab);
            app.DelaySlider_2Label.Text = {'Delay'; ''};
            app.DelaySlider_2 = uislider(app.Canal1Tab);
            app.DelaySlider_2.Limits = [0 25];
            app.DelaySlider_2.ValueChangedFcn = createCallbackFcn(app, @DelaySlider_2ValueChanged, true);
            app.DelaySlider_2.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25];
            app.PararaudioButton = uibutton(app.Canal1Tab, 'push');
            app.PararaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PararaudioButtonPushed, true);
            app.PararaudioButton.Text = 'Parar audio';
            app.Canal2Tab = uitab(app.TabGroup);
            app.Canal2Tab.Title = 'Canal 2';
            app.DistorcinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.DistorcinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBox_2ValueChanged, true);
            app.DistorcinCheckBox_2.Text = 'Distorción';
            app.VibratoCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.VibratoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox_2ValueChanged, true);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.EchoDelayCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.EchoDelayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBox_2ValueChanged, true);
            app.EchoDelayCheckBox_2.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.ReverberacinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBox_2ValueChanged, true);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.PhaserCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.PhaserCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBox_2ValueChanged, true);
            app.PhaserCheckBox_2.Text = 'Phaser ';
            app.FrecuenciadecentroEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecentroEditField_2Label.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecentroEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditField_2ValueChanged, true);
            app.FactordecalidadEditField_5Label = uilabel(app.Canal2Tab);
            app.FactordecalidadEditField_5Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_5 = uieditfield(app.Canal2Tab, 'numeric');
            app.FactordecalidadEditField_5.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_5ValueChanged, true);
            app.FrecuenciadecortealtaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortealtaEditField_2Label.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortealtaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortealtaEditField_2ValueChanged, true);
            app.ReproduciroriginalButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproduciroriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_2.Text = 'Reproducir original';
            app.ReproducirnuevoButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproducirnuevoButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton_2.Text = 'Reproducir nuevo';
            app.FiltroDropDown_2Label = uilabel(app.Canal2Tab);
            app.FiltroDropDown_2Label.Text = 'Filtro ';
            app.FiltroDropDown_2 = uidropdown(app.Canal2Tab);
            app.FiltroDropDown_2.Items = {'Freq. centro y factor de calidad', 'Frecuencias de corte'};
            app.FiltroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDown_2ValueChanged, true);
            app.FiltroDropDown_2.Value = 'Freq. centro y factor de calidad';
            app.AplicarefectosButton_2 = uibutton(app.Canal2Tab, 'push');
            app.AplicarefectosButton_2.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectosButton_2Pushed, true);
            app.AplicarefectosButton_2.Text = 'Aplicar efectos';
            app.msdeatrasoEditField_2Label = uilabel(app.Canal2Tab);
            app.msdeatrasoEditField_2Label.Text = 'ms de atraso';
            app.msdeatrasoEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.msdeatrasoEditField_2.ValueChangedFcn = createCallbackFcn(app, @msdeatrasoEditField_2ValueChanged, true);
            app.GananciaKnob_2Label = uilabel(app.Canal2Tab);
            app.GananciaKnob_2Label.Text = 'Ganancia';
            app.GananciaKnob_2 = uiknob(app.Canal2Tab, 'continuous');
            app.GananciaKnob_2.Limits = [0 1];
            app.GananciaKnob_2.ValueChangedFcn = createCallbackFcn(app, @GananciaKnob_2ValueChanged, true);
            app.FreqHzSlider_4Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_4Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_4 = uislider(app.Canal2Tab);
            app.FreqHzSlider_4.Limits = [0 10];
            app.FreqHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_4ValueChanged, true);
            app.FreqHzSlider_2Label = uilabel(app.Canal2Tab);
            app.FreqHzSlider_2Label.Text = 'Freq. (Hz)';
            app.FreqHzSlider_2 = uislider(app.Canal2Tab);
            app.FreqHzSlider_2.Limits = [0 5];
            app.FreqHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzSlider_2ValueChanged, true);
            app.DelaySliderLabel = uilabel(app.Canal2Tab);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.Canal2Tab);
            app.DelaySlider.Limits = [0 25];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.FreqHzEditField_2Label = uilabel(app.Canal2Tab);
            app.FreqHzEditField_2Label.Text = 'Freq. (Hz)';
            app.FreqHzEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FreqHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FreqHzEditField_2ValueChanged, true);
            app.FrecuenciadecortebajaEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecortebajaEditField_2Label.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecortebajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecortebajaEditField_2ValueChanged, true);
            app.GrficasfiltroTab = uitab(app.TabGroup);
            app.GrficasfiltroTab.Title = 'Gráficas filtro';
            app.UIAxes = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes, 'Filtro canal 1')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'Magnitud (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes2, 'Filtro canal 2')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Magnitud (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.ReproduciroriginalButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproduciroriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_3.Text = 'Reproducir original';
            app.ReproducirnuevoButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproducirnuevoButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton_3.Text = 'Reproducir nuevo';
            app.GrficasdeaudioTab = uitab(app.TabGroup);
            app.GrficasdeaudioTab.Title = 'Gráficas de audio';
            app.UIAxes3 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes3, 'Audio original')
            xlabel(app.UIAxes3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes3, 'Magnitud (dB)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes4, 'Audio modificado')
            xlabel(app.UIAxes4, 'Frecuencia (Hz)')
            ylabel(app.UIAxes4, 'Magnitud (dB)')
            zlabel(app.UIAxes4, 'Z')
            app.ReproduciroriginalButton_5 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproduciroriginalButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_5.Text = 'Reproducir original';
            app.ReproducirnuevoButton_4 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproducirnuevoButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton_4.Text = 'Reproducir nuevo';
            app.GuardarTab = uitab(app.TabGroup);
            app.GuardarTab.Title = 'Guardar ';
            app.ReproduciroriginalButton_6 = uibutton(app.GuardarTab, 'push');
            app.ReproduciroriginalButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_6.Text = 'Reproducir original';
            app.ReproducirnuevoButton_5 = uibutton(app.GuardarTab, 'push');
            app.ReproducirnuevoButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproducirnuevoButtonPushed, true);
            app.ReproducirnuevoButton_5.Text = 'Reproducir nuevo';
            app.GuardarnuevocomowavButton = uibutton(app.GuardarTab, 'push');
            app.GuardarnuevocomowavButton.Text = 'Guardar nuevo como .wav';
            app.GuardarvariablescomomatButton = uibutton(app.GuardarTab, 'push');
            app.GuardarvariablescomomatButton.Text = 'Guardar variables como .mat';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Proyecto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end